classdef methodSelector < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        NumiiSolveUIFigure              matlab.ui.Figure
        NumiiSolveLabel                 matlab.ui.control.Label
        MathproblemIdoitforyouLabel     matlab.ui.control.Label
        ChooseyourtypeofproblemButtonGroup  matlab.ui.container.ButtonGroup
        NolinearequationButton_2        matlab.ui.control.ToggleButton
        NumericalintegrationButton_2    matlab.ui.control.ToggleButton
        NolinearequationsystemButton_2  matlab.ui.control.ToggleButton
        DiferentialequationsystemButton_2  matlab.ui.control.ToggleButton
        ListBox                         matlab.ui.control.ListBox
        ListBox_2                       matlab.ui.control.ListBox
        ListBox_3                       matlab.ui.control.ListBox
        ListBox_4                       matlab.ui.control.ListBox
        uiAxesError                     matlab.ui.control.UIAxes
        EcuationEditField               matlab.ui.control.EditField
        RangeofIntervalLabel            matlab.ui.control.Label
        MinEditField_2Label             matlab.ui.control.Label
        MinEditField_2                  matlab.ui.control.NumericEditField
        MaxEditField_2Label             matlab.ui.control.Label
        MaxEditField_2                  matlab.ui.control.NumericEditField
        IterationsLabel                 matlab.ui.control.Label
        IterationsEditField             matlab.ui.control.NumericEditField
        CALCULARButton                  matlab.ui.control.Button
        RaizLabel                       matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        EaLabel                         matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        EcuationxLabel                  matlab.ui.control.Label
        UITable                         matlab.ui.control.Table
        UITable2                        matlab.ui.control.Table
        XSpinnerLabel                   matlab.ui.control.Label
        XSpinner                        matlab.ui.control.Spinner
        UITable3                        matlab.ui.control.Table
        InitialValueEditFieldLabel      matlab.ui.control.Label
        InitialValueEditField           matlab.ui.control.NumericEditField
        DerivadaLabel                   matlab.ui.control.Label
        Label2                          matlab.ui.control.Label
        AXLabel                         matlab.ui.control.Label
        BLabel                          matlab.ui.control.Label
    end

    
    methods (Access = private)
        
        function results = func(app)
            % Se hace desaparecer todo y los valores se reinician
            app.ListBox_4.Visible = false;
            app.ListBox_3.Visible = false;
            app.ListBox_2.Visible = false;
            app.ListBox.Visible = false;
            % Respuesta
            app.Label.Visible = false;
            app.Label_2.Visible = false;
            app.RaizLabel.Visible = false;
            app.EaLabel.Visible = false;
            % Grafico
            app.uiAxesError.Visible = false;
            % Falta una manera de borrar los datos de la grafica
            % Tablas
            app.UITable.Visible = false;
            app.UITable.Enable = 'on';
            app.UITable.Data = zeros(2);
            app.UITable2.Visible = false;
            app.UITable2.Enable = 'on';
            app.UITable2.Data = zeros(2,1);
            app.UITable3.Visible = false;
            app.AXLabel.Visible = false;
            app.BLabel.Visible = false;
            % x#
            app.XSpinner.Visible = false;
            app.XSpinner.Value = 2;
            app.XSpinnerLabel.Visible = false;
            % Valor inicial
            app.InitialValueEditFieldLabel.Visible = false;
            app.InitialValueEditField.Visible = false;
            app.InitialValueEditField.Value = 0;
            % Ecuacion
            app.EcuationEditField.Visible = false;
            app.EcuationxLabel.Visible = false;
            app.EcuationEditField.Value = "";
            % Rango
            app.MaxEditField_2.Visible = false;
            app.MaxEditField_2Label.Visible = false;
            app.MaxEditField_2.Value = 0;
            app.MinEditField_2Label.Visible = false;
            app.MinEditField_2.Visible = false;
            app.MinEditField_2.Value = 0;
            app.RangeofIntervalLabel.Visible = false;
            % Iteraciones
            app.IterationsLabel.Visible = false;
            app.IterationsEditField.Visible = false;
            app.IterationsEditField.Value = 1;
            % Derivada
            app.DerivadaLabel.Visible = false;
            app.Label2.Visible = false;
        end
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            func(app);
            % por default esta seleccionado Nolinearequation y Biseccion
            app.ListBox_2.Value = "Bisection";
            app.ListBox_2.Visible = true;
            app.uiAxesError.Visible = true;
            % Ecuacion
            app.EcuationEditField.Visible = true;
            app.EcuationxLabel.Visible = true;
            % Rango
            app.MaxEditField_2.Visible = true;
            app.MaxEditField_2Label.Visible = true;
            app.MinEditField_2Label.Visible = true;
            app.MinEditField_2.Visible = true;
            app.RangeofIntervalLabel.Visible = true;
            % Iteraciones
            app.IterationsLabel.Visible = true;
            app.IterationsEditField.Visible = true;
            % Grafico
            app.uiAxesError.Visible = true;
        end

        % Selection changed function: 
        % ChooseyourtypeofproblemButtonGroup
        function ChooseyourtypeofproblemButtonGroupSelectionChanged(app, event)
            selectedButton = app.ChooseyourtypeofproblemButtonGroup.SelectedObject;
            % Desaparece todo
            func(app);
            % Aparece solo lo que se selecciona
            switch(selectedButton)
                case app.NolinearequationButton_2
                    % por default esta seleccionado Biseccion
                    app.ListBox_2.Visible=true;
                    app.ListBox_2.Value = "Bisection";
                    % objetos que necesita
                    % Ecuacion
                    app.EcuationEditField.Visible = true;
                    app.EcuationxLabel.Visible = true;
                    % Rango
                    app.MaxEditField_2.Visible = true;
                    app.MaxEditField_2Label.Visible = true;
                    app.MinEditField_2Label.Visible = true;
                    app.MinEditField_2.Visible = true;
                    app.RangeofIntervalLabel.Visible = true;
                    % Iteraciones
                    app.IterationsLabel.Visible = true;
                    app.IterationsEditField.Visible = true;
                    % Grafico
                    app.uiAxesError.Visible = true;
                case app.NumericalintegrationButton_2
                    app.ListBox_3.Visible=true;
                    % por default esta Trapezoidal multiple
                    app.ListBox_3.Value = "Trapezoidal multiple";
                    % falta codigo
                case app.NolinearequationsystemButton_2
                    app.ListBox.Visible=true;
                    % por default esta seleccionado Gauss-Seidel
                    app.ListBox.Value = "Gauss-Seidel";
                    % objetos que necesita
                    % Tablas
                    app.UITable.Visible = true;
                    app.UITable2.Visible = true;
                    app.AXLabel.Visible = true;
                    app.BLabel.Visible = true;
                    % Iteraciones
                    app.IterationsLabel.Visible = true;
                    app.IterationsEditField.Visible = true;
                    % x#
                    app.XSpinner.Visible = true;
                    app.XSpinnerLabel.Visible = true;
                case app.DiferentialequationsystemButton_2
                    app.ListBox_4.Visible=true;
                    % por default esta seleccionado Euler
                    app.ListBox_4.Value = "Euler";
                    % falta codigo
            end
        end

        % Value changed function: ListBox_4
        function ListBox_4ValueChanged(app, event)
            value = app.ListBox_4.Value;
            % se vuelve a eliminar todo
            func(app);
            % objetos que todas las funciones necesitan
            app.ListBox_4.Visible = true;
            % objetos de cada funcion
            if(value == "Euler")
                % falta codigo
            elseif(value == "Clasico Runge-kutta")
                % falta codigo
            end
        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            value = app.ListBox.Value;
            % se vuelve a eliminar todo
            func(app);
            % objetos que todas las funciones necesitan
            app.ListBox.Visible = true;
            % Tablas
            app.UITable.Visible = true;
            app.UITable2.Visible = true;
            app.AXLabel.Visible = true;
            app.BLabel.Visible = true;
            % Iteraciones
            app.IterationsLabel.Visible = true;
            app.IterationsEditField.Visible = true;
            % x#
            app.XSpinner.Visible = true;
            app.XSpinnerLabel.Visible = true;
            % objetos de cada funcion
            if(value == "Gauss-Seidel")
                %
            elseif(value == "Newton-Raphson")
                % falta codigo
            end
        end

        % Value changed function: ListBox_2
        function ListBox_2ValueChanged(app, event)
            value = app.ListBox_2.Value;
            % se vuelve a eliminar todo
            func(app);
            % objetos que todas las funciones necesitan
            app.ListBox_2.Visible = true;
            % Ecuacion
            app.EcuationEditField.Visible = true;
            app.EcuationxLabel.Visible = true;
            % Iteraciones
            app.IterationsLabel.Visible = true;
            app.IterationsEditField.Visible = true;
            % Grafico
            app.uiAxesError.Visible = true;
            % objetos de cada funcion
            if(value == "Bisection" || value == "Regular Falsi")
                % Rango
                app.MaxEditField_2.Visible = true;
                app.MaxEditField_2Label.Visible = true;
                app.MinEditField_2Label.Visible = true;
                app.MinEditField_2.Visible = true;
                app.RangeofIntervalLabel.Visible = true;
            elseif(value == "Simple Iteration fix point")
                % Valor inicial
                app.InitialValueEditFieldLabel.Visible = true;
                app.InitialValueEditField.Visible = true;
            elseif(value =="Newton- Raphson")
                % Valor inicial
                app.InitialValueEditFieldLabel.Visible = true;
                app.InitialValueEditField.Visible = true;
                % Derivada
                app.DerivadaLabel.Visible = true;
            end
        end

        % Value changed function: ListBox_3
        function ListBox_3ValueChanged(app, event)
            value = app.ListBox_3.Value;
            % se vuelve a eliminar todo
            func(app);
            % objetos que todas las funciones necesitan
            app.ListBox_3.Visible = true;
            % objetos de cada funcion
            if(value == "Trapezoidal multiple")
                % falta codigo
            elseif(value == "Simpson 3/8 multiple")
                % falta codigo
            end
        end

        % Button pushed function: CALCULARButton
        function CALCULARButtonPushed(app, event)
            
            % Se asume que los valores ingresados son validos
            switch(app.ChooseyourtypeofproblemButtonGroup.SelectedObject)
                case app.NolinearequationButton_2
                    switch(app.ListBox_2.Value)
                        case "Bisection"
                            handle = app.EcuationEditField.Value;
                            max = app.MaxEditField_2.Value;
                            min = app.MinEditField_2.Value;
                            ite = app.IterationsEditField.Value;
                            [raiz, Ea] = bisection(handle,min,max,ite);
                            plot(app.uiAxesError,Ea);
                        case "Regular Falsi"
                            
                        case "Simple Iteration fix point"
                            
                        case "Newton- Raphson"
                            
                    end
                case app.NumericalintegrationButton_2
                    switch(app.ListBox_3.Value)
                        case "Trapezoidal multiple"
                            
                        case "Simpson 3/8 multiple"
                            
                    end
                case app.NolinearequationsystemButton_2
                    switch(app.ListBox.Value)
                        case "Gauss-Seidel"
                            
                        case "Newton-Raphson"
                            
                    end
                case app.DiferentialequationsystemButton_2
                    
            end
        end

        % Value changed function: XSpinner
        function XSpinnerValueChanged(app, event)
            value = app.XSpinner.Value;
            % cada vez que se cambia el valor, se actualizan las tablas
            app.UITable.Data = zeros(value);
            app.UITable2.Data = zeros(value,1);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.NumiiSolveUIFigure = uifigure;
            app.NumiiSolveUIFigure.Name = 'Numii Solve';
            app.NumiiSolveLabel = uilabel(app.NumiiSolveUIFigure);
            app.NumiiSolveLabel.Text = 'Numii Solve';
            app.MathproblemIdoitforyouLabel = uilabel(app.NumiiSolveUIFigure);
            app.MathproblemIdoitforyouLabel.Text = 'Math problem? I do it for you!';
            app.ChooseyourtypeofproblemButtonGroup = uibuttongroup(app.NumiiSolveUIFigure);
            app.ChooseyourtypeofproblemButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ChooseyourtypeofproblemButtonGroupSelectionChanged, true);
            app.ChooseyourtypeofproblemButtonGroup.Title = 'Choose your type of problem:';
            app.NolinearequationButton_2 = uitogglebutton(app.ChooseyourtypeofproblemButtonGroup);
            app.NolinearequationButton_2.Text = 'No linear equation';
            app.NolinearequationButton_2.Value = true;
            app.NumericalintegrationButton_2 = uitogglebutton(app.ChooseyourtypeofproblemButtonGroup);
            app.NumericalintegrationButton_2.Text = 'Numerical integration';
            app.NolinearequationsystemButton_2 = uitogglebutton(app.ChooseyourtypeofproblemButtonGroup);
            app.NolinearequationsystemButton_2.Text = 'No linear equation system';
            app.DiferentialequationsystemButton_2 = uitogglebutton(app.ChooseyourtypeofproblemButtonGroup);
            app.DiferentialequationsystemButton_2.Text = 'Diferential equation system';
            app.ListBox = uilistbox(app.NumiiSolveUIFigure);
            app.ListBox.Items = {'Gauss-Seidel', 'Newton-Raphson'};
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.ListBox.Value = 'Newton-Raphson';
            app.ListBox_2 = uilistbox(app.NumiiSolveUIFigure);
            app.ListBox_2.Items = {'Bisection', 'Regular Falsi', 'Simple Iteration fix point', 'Newton- Raphson'};
            app.ListBox_2.ValueChangedFcn = createCallbackFcn(app, @ListBox_2ValueChanged, true);
            app.ListBox_2.Value = 'Bisection';
            app.ListBox_3 = uilistbox(app.NumiiSolveUIFigure);
            app.ListBox_3.Items = {'Trapezoidal multiple', 'Simpson 3/8 multiple'};
            app.ListBox_3.ValueChangedFcn = createCallbackFcn(app, @ListBox_3ValueChanged, true);
            app.ListBox_3.Value = 'Trapezoidal multiple';
            app.ListBox_4 = uilistbox(app.NumiiSolveUIFigure);
            app.ListBox_4.Items = {'Euler', 'Clasico Runge-kutta'};
            app.ListBox_4.ValueChangedFcn = createCallbackFcn(app, @ListBox_4ValueChanged, true);
            app.ListBox_4.Value = 'Euler';
            app.uiAxesError = uiaxes(app.NumiiSolveUIFigure);
            title(app.uiAxesError, 'Error')
            xlabel(app.uiAxesError, 'Iterations')
            ylabel(app.uiAxesError, 'Ea')
            app.EcuationEditField = uieditfield(app.NumiiSolveUIFigure, 'text');
            app.RangeofIntervalLabel = uilabel(app.NumiiSolveUIFigure);
            app.RangeofIntervalLabel.Text = 'Range of Interval';
            app.MinEditField_2Label = uilabel(app.NumiiSolveUIFigure);
            app.MinEditField_2Label.Text = 'Min';
            app.MinEditField_2 = uieditfield(app.NumiiSolveUIFigure, 'numeric');
            app.MaxEditField_2Label = uilabel(app.NumiiSolveUIFigure);
            app.MaxEditField_2Label.Text = 'Max';
            app.MaxEditField_2 = uieditfield(app.NumiiSolveUIFigure, 'numeric');
            app.IterationsLabel = uilabel(app.NumiiSolveUIFigure);
            app.IterationsLabel.Text = 'Iterations';
            app.IterationsEditField = uieditfield(app.NumiiSolveUIFigure, 'numeric');
            app.IterationsEditField.Limits = [1 Inf];
            app.IterationsEditField.Value = 1;
            app.CALCULARButton = uibutton(app.NumiiSolveUIFigure, 'push');
            app.CALCULARButton.ButtonPushedFcn = createCallbackFcn(app, @CALCULARButtonPushed, true);
            app.CALCULARButton.Text = 'CALCULAR';
            app.RaizLabel = uilabel(app.NumiiSolveUIFigure);
            app.RaizLabel.Text = 'Raiz';
            app.Label = uilabel(app.NumiiSolveUIFigure);
            app.EaLabel = uilabel(app.NumiiSolveUIFigure);
            app.EaLabel.Text = 'Ea';
            app.Label_2 = uilabel(app.NumiiSolveUIFigure);
            app.EcuationxLabel = uilabel(app.NumiiSolveUIFigure);
            app.EcuationxLabel.Text = 'Ecuation @(x)';
            app.UITable = uitable(app.NumiiSolveUIFigure);
            app.UITable.ColumnName = '';
            app.UITable.RowName = {};
            app.UITable2 = uitable(app.NumiiSolveUIFigure);
            app.UITable2.ColumnName = '';
            app.UITable2.RowName = {};
            app.XSpinnerLabel = uilabel(app.NumiiSolveUIFigure);
            app.XSpinnerLabel.Text = 'X';
            app.XSpinner = uispinner(app.NumiiSolveUIFigure);
            app.XSpinner.Limits = [2 Inf];
            app.XSpinner.RoundFractionalValues = 'on';
            app.XSpinner.ValueDisplayFormat = '%11g';
            app.XSpinner.ValueChangedFcn = createCallbackFcn(app, @XSpinnerValueChanged, true);
            app.XSpinner.Value = 2;
            app.UITable3 = uitable(app.NumiiSolveUIFigure);
            app.UITable3.ColumnName = '';
            app.UITable3.RowName = {};
            app.InitialValueEditFieldLabel = uilabel(app.NumiiSolveUIFigure);
            app.InitialValueEditFieldLabel.Text = 'Initial Value';
            app.InitialValueEditField = uieditfield(app.NumiiSolveUIFigure, 'numeric');
            app.DerivadaLabel = uilabel(app.NumiiSolveUIFigure);
            app.DerivadaLabel.Text = 'Derivada';
            app.Label2 = uilabel(app.NumiiSolveUIFigure);
            app.Label2.Text = 'Label2';
            app.AXLabel = uilabel(app.NumiiSolveUIFigure);
            app.AXLabel.Text = 'AX#';
            app.BLabel = uilabel(app.NumiiSolveUIFigure);
            app.BLabel.Text = 'B';
        end
    end

    methods (Access = public)

        % Construct app
        function app = methodSelector

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.NumiiSolveUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.NumiiSolveUIFigure)
        end
    end
end